classdef run_mmc_pl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        notify_pretestLabel        matlab.ui.control.Label
        Setup2ProgressLabel        matlab.ui.control.Label
        Setup1ProgressLabel        matlab.ui.control.Label
        ProgressTextLabel_2        matlab.ui.control.Label
        ProgressTextLabel          matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        Setup1Tab                  matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        ExperimentHistoryPanel     matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        Panel_4                    matlab.ui.container.Panel
        LogWeightButton            matlab.ui.control.Button
        WeightEditFieldLabel_1     matlab.ui.control.Label
        EnterWeightEditField       matlab.ui.control.NumericEditField
        MouseIDDropDown            matlab.ui.control.DropDown
        MouseIDDropDownLabel       matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        GroupLabel                 matlab.ui.control.Label
        SexLabel                   matlab.ui.control.Label
        GenotypeLabel              matlab.ui.control.Label
        DobLabel                   matlab.ui.control.Label
        AgeLabel                   matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        StopSetup1Button           matlab.ui.control.Button
        RunSetup1Button            matlab.ui.control.StateButton
        UserDropDown               matlab.ui.control.DropDown
        UserDropDownLabel          matlab.ui.control.Label
        ExperimentDropDown         matlab.ui.control.DropDown
        ExperimentDropDownLabel    matlab.ui.control.Label
        saveLabel                  matlab.ui.control.Label
        DateandtimeLabel           matlab.ui.control.Label
        Setup2Tab                  matlab.ui.container.Tab
        Panel_5                    matlab.ui.container.Panel
        ExperimentHistoryPanel_2   matlab.ui.container.Panel
        UITable_2                  matlab.ui.control.Table
        Panel_8                    matlab.ui.container.Panel
        LogWeightButton_2          matlab.ui.control.Button
        WeightEditFieldLabel_2     matlab.ui.control.Label
        EnterWeightEditField_2     matlab.ui.control.NumericEditField
        MouseIDDropDown_2          matlab.ui.control.DropDown
        MouseIDDropDown_2Label     matlab.ui.control.Label
        Panel_7                    matlab.ui.container.Panel
        GridLayout_2               matlab.ui.container.GridLayout
        GroupLabel_2               matlab.ui.control.Label
        SexLabel_2                 matlab.ui.control.Label
        GenotypeLabel_2            matlab.ui.control.Label
        DobLabel_2                 matlab.ui.control.Label
        AgeLabel_2                 matlab.ui.control.Label
        Panel_6                    matlab.ui.container.Panel
        StopSetup2Button           matlab.ui.control.Button
        RunSetup2Button            matlab.ui.control.StateButton
        UserDropDown_2             matlab.ui.control.DropDown
        UserDropDown_2Label        matlab.ui.control.Label
        ExperimentDropDown_2       matlab.ui.control.DropDown
        ExperimentDropDown_2Label  matlab.ui.control.Label
        saveLabel_2                matlab.ui.control.Label
        DateandtimeLabel_2         matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        group
        dob
        sex
        genotype
        datetimenow 
        age
        basefolder
        mouseID
        name
        experiment
        savedir
        savefile
        savefile2
        data
        datetime
        tempdir
        splists
    end
    
    properties (Access = public)
        ph
        th
        setup1
        setup2  
    end
    
  
    methods (Access = private)
        
        function results = read_spreadsheet(app)
            %load spreadsheet
            base_folder = fileparts(mfilename('fullpath'));
            spreadsheet_filename = fullfile(base_folder, 'metadata_db_pl.xls');
            
            %extract values from spreadsheet 
            [~,sUser] = xlsread(spreadsheet_filename, "User", 'A2:A100');
            [~,sExperiment] = xlsread(spreadsheet_filename, "Experiment", 'A2:A100');
            app.experiment = sExperiment;
            app.name = sUser;
            mouseID = xlsread(spreadsheet_filename, "Mouse", 'A2:A100');
            app.mouseID = string(mouseID);
            
            group = xlsread(spreadsheet_filename, "Mouse", 'B2:B100');
            sgroup = string(group);
            
            ndob = xlsread(spreadsheet_filename, "Mouse", 'C2:C100');

            if isempty(ndob)
                ndob = xlsread(spreadsheet_filename, "Mouse", '', 'basic');
                ndob = ndob(2:end,3);
            end
            
            chardob = datestr(ndob+datenum('30-Dec-1899'));
            strdob = datetime(chardob,'InputFormat','dd-MMM-yyyy');
            sdob = string(strdob);
            nowt = datetime('now');
            timedate = string(between(strdob,nowt,'months'));
            
            [~,ssex] = xlsread(spreadsheet_filename, "Mouse", 'D2:D100');
            [~,sgenotype] = xlsread(spreadsheet_filename, "Mouse", 'E2:E100');
               
            app.group = sgroup;
            app.dob = sdob;
            app.sex = ssex;
            app.genotype = sgenotype;
            app.age = timedate;
           
            %values to app variables 
            
            app.UserDropDown.Items = app.name;
            app.UserDropDown.ItemsData = 1:200;
            
            app.UserDropDown_2.Items = app.name;
            app.UserDropDown_2.ItemsData = 1:200;
            
            app.ExperimentDropDown.Items = app.experiment;
            app.ExperimentDropDown.ItemsData = 1:200;
            
            app.ExperimentDropDown_2.Items = app.experiment;
            app.ExperimentDropDown_2.ItemsData = 1:200;
            
            app.MouseIDDropDown.Items = ['Select a Mouse'; app.mouseID];
            app.MouseIDDropDown.ItemsData = 1:200;
            
            app.MouseIDDropDown_2.Items = ['Select a Mouse'; app.mouseID];
            app.MouseIDDropDown_2.ItemsData = 1:200;
          
            app.data.time = [89 ,74, 62, 49, 40, 31];
        end
        
        
        function results = check_setup1_progress(app)
            read_prog = 0;
            while read_prog==0
                try
                    load(app.progress1dir,'progress');
                    app.ph.XData = [0 progress.val progress.val 0]; 
                    if progress.val==100
                        app.StopSetup1Button.Enable = 'off';
                        app.RunSetup1Button.Enable = 'on';
                        stop(app.setup1_progtimer);
                    end
                    app.ProgressTextLabel.Text = progress.string;
                    drawnow %update graphics 
                    read_prog = 1;
                catch
                    pause(0.1)
                    read_prog = 0;
                end
            end
        end
        
        function results = check_setup2_progress(app)
            read_prog = 0;
            while read_prog==0
                try
                    load(app.progress2dir,'progress');
                    app.ph_2.XData = [0 progress.val progress.val 0]; 
                    if progress.val==100
                        app.StopSetup2Button.Enable = 'off';
                        app.RunSetup2Button.Enable = 'on';
                        stop(app.setup2_progtimer);
                    end
                    app.ProgressTextLabel_2.Text = progress.string;
                    drawnow %update graphics 
                    read_prog = 1;
                catch
                    pause(0.1)
                    read_prog = 0;
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %stop button setting before running experiment
            app.StopSetup1Button.Enable ='off';
            app.StopSetup2Button.Enable ='off';

            %check/create folders
            app.basefolder = fileparts(mfilename('fullpath'));
            read_spreadsheet(app);
            
            D = fullfile(app.basefolder, 'data');
            app.savedir = D;
            if ~exist(D,'dir')
                mkdir(D);
            end
            
            D = fullfile(app.basefolder, 'temp');
            app.tempdir = D;
            if ~exist(D,'dir')
                mkdir(D);
            end
            
            app.saveLabel.Text = ['Save Directory: ' app.savedir];
            app.saveLabel_2.Text = ['Save Directory: ' app.savedir];
            date = datestr(now,'mm-dd-yy HH-MM-SS');
            splitdate = split(date," ");
            
            app.DateandtimeLabel.Text = ['Date: ' num2str(splitdate{1}) '      Time: ' num2str(splitdate{2})];
            app.DateandtimeLabel_2.Text = ['Date: ' num2str(splitdate{1}) '      Time: ' num2str(splitdate{2})];

            app.UIAxes.Visible = 'off';
            app.ProgressTextLabel.Text = '';
            
            app.UIAxes_2.Visible = 'off';
            app.ProgressTextLabel_2.Text = '';
            app.notify_pretestLabel.Visible='off';
            
            %check all connected ports; find devices for all setups
            app.ProgressTextLabel.Text = 'Checking connected devices';
            app.splists = mmc_detect_pl();
            app.ProgressTextLabel.Text = '';
        end

        % Value changed function: RunSetup1Button
        function RunSetup1(app, event)
            app.RunSetup1Button.Enable = 'off';
            %%% disable other edit fields as well
            app.StopSetup1Button.Enable = 'on';
            app.ProgressTextLabel.Text = 'Preparing Displays';
            
            %organize experiment metadata
            value = app.RunSetup1Button.Value;
            D = app.savedir;
            N = datestr(now,'mm-dd-yy HH-MM-SS');
            app.DateandtimeLabel.Text = N;
            app.setup1.metadata.datetime = N;
            app.setup1.metadata.datenum = now;
  
            app.setup1.metadata.user = app.name{app.UserDropDown.Value};
            app.setup1.metadata.experiment = app.experiment{app.ExperimentDropDown.Value};
            
            F = [N ' ' app.setup1.metadata.mouseID ' ' app.setup1.metadata.experiment];
            app.setup1.savefile = [fullfile(D,F), '.mat'];
            app.setup1.metadata.savedir = fullfile(D,F);
            
            %save metadata to temporary file
            metadata = app.setup1.metadata;
            metadata.setupnum = 1;
            metadata.splist = app.splists(:,1);
            save(fullfile(app.tempdir,'current_metadata'),'metadata','-mat')
            
            %save progress to temporary file
            progress.val = 0;
            progress.string = '';
            app.progress1dir = fullfile(app.tempdir,'setup1_progress.mat');
            save(app.progress1dir,'progress')
            
            %save stop to temporary file
            stop = 0;
            save(fullfile(app.tempdir,'setup1_stop'),'stop','-mat')
            
            % Set up the progress bar axis
            app.UIAxes.Visible = 'on';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YLabel.String ='';
            app.UIAxes.XLabel.String ='';
            app.UIAxes.XLim = [0,100];
            
            % Create empty patch that will be updated
            app.ph = patch(app.UIAxes,[0 0 0 0],[0 0 2 2],[0.67578 1 0.18359]); %greenyellow
         
            %assign chosen exp from dropdown as setup1 experiment name
            expval = app.ExperimentDropDown.Value;
            expname = app.ExperimentDropDown.Items{expval};
            
            %Check if pretest has been done before 
            datalist = dir(fullfile(app.savedir,'*.mat'));
            need_pretest = 1;
            if ~isempty(datalist)
                exphist = cell(1,length(datalist));
                elapstime = nan(1,length(datalist));
                
                 for i = 1:length(datalist)
                    load(fullfile(app.savedir,datalist(i).name),'exp');
                    exphist{i} = exp.metadata.experiment;
                    elapstime(i) = etime(datevec(now),datevec(exp.metadata.datenum));
                 end
                 recentexps_inds = find(elapstime<7200);
                 
                 
                 for i = recentexps_inds
                    if strcmp(exphist{i},'pretest')
                        need_pretest = 0;
                    end
                 end
            end
            
            if strcmp(expname,'pretest')==0 && need_pretest
                app.ProgressTextLabel.Text = 'run ''Pretest'' before any other experiments';
                pause(0.5);
                app.RunSetup1Button.Enable = 'on';
                app.StopSetup1Button.Enable = 'off';
                return;
            end
           
            app.setup1_worker = batch(expname);
            app.setup1_progtimer = timer('StartDelay',5,'Period',1,'TimerFcn','check_setup1_progress(app)');
            start(app.setup1_progtimer)
        end

        % Value changed function: MouseIDDropDown
        function SelectID(app, event)
            fvalue = app.MouseIDDropDown.Value;
            value = fvalue - 1;
            
            if value>0
            
            %Print Labels 
            app.GroupLabel.Text = 'Group: '+ app.group(value);
            app.DobLabel.Text = 'Date of Birth: '+app.dob(value); 
            app.SexLabel.Text = ['Sex: ' app.sex{value}];
            app.GenotypeLabel.Text =  ['Genotype: ' app.genotype{value}];
            app.AgeLabel.Text = ['Age: ' app.age{value} 'nths'];
           
            %mouse info into meta struct
            app.setup1.metadata.mouseID =  app.mouseID{value};
            app.setup1.metadata.mouseAge = [app.age{value} 'nths'];
            app.setup1.metadata.group =  app.group{value};
            app.setup1.metadata.dob = app.dob{value} ;
            app.setup1.metadata.sex = app.sex{value};
            app.setup1.metadata.genotype = app.genotype{value};
             
              datalist = dir(fullfile(app.savedir,'*.mat'));
              history = cell(1,11);
              n = 0 ;
              column = cell(1,11);
              for i = 1:length(datalist)
                load(fullfile(app.savedir,datalist(i).name),'exp');
                
                column = fieldnames(exp.metadata);
                mID = exp.metadata.mouseID;
                
                if mID == app.mouseID(value)
                  
                   if n == 0
                   
                       history = (struct2cell(exp.metadata))';
                       n = 1;
                   else  
                    n = size(history,1);
                    history(n+1,:) = (struct2cell(exp.metadata))';
                   end
                end
              end
              
              app.UITable.Data = history(:,[1,7,11,10]);
              app.UITable.ColumnName = column([1,7,11,10]);
            else
                %%%%clear relevant metadata values, UItable, and text
                %%%%labels
            end
            
        end

        % Value changed function: ExperimentDropDown
        function selectExp(app, event)
            value = app.ExperimentDropDown.Value;
            
        end

        % Value changed function: UserDropDown
        function selectUser(app, event)
            value = app.UserDropDown.Value;
            
        end

        % Value changed function: RunSetup2Button
        function RunSetup2(app, event)
            app.RunSetup2Button.Enable = 'off';
            app.StopSetup2Button.Enable = 'on';
            app.ProgressTextLabel.Text = 'Preparing Displays';

            value = app.RunSetup2Button.Value;
            D = app.savedir;
            N = datestr(now,'mm-dd-yy HH-MM-SS');
            app.DateandtimeLabel_2.Text = N;

            app.setup2.metadata.datetime = N;
            app.setup2.metadata.datenum = now;
            app.setup2.metadata.savedir = fullfile(D,N);
            app.setup2.metadata.user = app.name{app.UserDropDown_2.Value};
            app.setup2.metadata.experiment = app.experiment{app.ExperimentDropDown_2.Value};
 
            F = [N ' ' app.setup2.metadata.mouseID ' ' app.setup2.metadata.experiment];
            app.setup2.savefile = [fullfile(D,F), '.mat'];
            % Set up the progress bar axis
            app.UIAxes_2.Visible = 'on';
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YLabel.String ='';
            app.UIAxes_2.XLabel.String ='';
            app.UIAxes_2.XLim = [0,100];
            
            % Create empty patch that will be updated
            app.ph_2 = patch(app.UIAxes_2,[0 0 0 0],[0 0 2 2],[0.67578 1 0.18359]); %greenyellow
         
 
            %connect to controller and displays
            [controller, display1, display2, display3, display4] = mmc_connect();
            app.setup2.controller = controller;
            app.setup2.display1 = display1;
            app.setup2.display2 = display2;
            app.setup2.display3 = display3;
            app.setup2.display4 = display4;
            
            %assign chosen exp from dropdown as setup1 experiment name
            expval = app.ExperimentDropDown.Value;
            expname = app.ExperimentDropDown.Items{expval};
            
            protocol = mmc_load_protocol(expname, '2'); %load protocol
            app.setup2.protocol = protocol;
            mmc_run_setup2(app.setup2, 1, 'start', clock);%Set exp variable for setup 1 and run script1 with variable 
            
            %Check if pretest has been done before 
            datalist = dir(fullfile(app.savedir,'*.mat'));
            need_pretest = 1;
            if ~isempty(datalist)
                exphist = cell(1,length(datalist));
                elapstime = nan(1,length(datalist));
                
                 for i = 1:length(datalist)
                    load(fullfile(app.savedir,datalist(i).name),'exp');
                    exphist{i} = exp.metadata.experiment;
                    elapstime(i) = etime(datevec(now),datevec(exp.metadata.datenum));
                 end
                 recentexps_inds = find(elapstime<7200);
                 
                 for i = recentexps_inds
                    if strcmp(exphist{i},'pretest')
                        need_pretest = 0;
                    end
                 end
            end
            
            if strcmp(expname,'pretest')==0 && need_pretest
                app.ProgressTextLabel_2.Text = 'run ''Pretest'' before any other experiments';
                pause(0.5);
                app.RunSetup2Button.Enable = 'on';
                app.StopSetup2Button.Enable = 'off';
                return;
            end
             
            global exp
            exp = app.setup2;
            exp.setupnum = 2;
            exp.stop = 0;
            eval(['run ' expname '.m']);
        end

        % Value changed function: UserDropDown_2
        function selectUser2(app, event)
            value = app.UserDropDown_2.Value;
            
        end

        % Value changed function: ExperimentDropDown_2
        function selectExp2(app, event)
            value = app.ExperimentDropDown_2.Value;
            
        end

        % Value changed function: MouseIDDropDown_2
        function SelectID2(app, event)
            fvalue = app.MouseIDDropDown_2.Value;
            value = fvalue - 1;
            
            if app.MouseIDDropDown_2.ItemsData == 1
 
            else
            
            %Print Labels 
            app.GroupLabel_2.Text = 'Group: '+ app.group(value);
            app.DobLabel_2.Text = 'Date of Birth: '+app.dob(value); 
            app.SexLabel_2.Text = ['Sex: ' app.sex{value}];
            app.GenotypeLabel_2.Text =  ['Genotype: ' app.genotype{value}];
            app.AgeLabel_2.Text = ['Age: ' app.age{value} 'nths'];
           
            %mouse info into meta struct
            app.setup2.metadata.mouseID =  app.mouseID{value};
            app.setup2.metadata.mouseAge = [app.age{value} 'nths'];
            app.setup2.metadata.group =  app.group{value};
            app.setup2.metadata.dob = app.dob{value} ;
            app.setup2.metadata.sex = app.sex{value};
            app.setup2.metadata.genotype = app.genotype{value};
            
          end
         
          datalist = dir(fullfile(app.savedir,'*.mat'));
             history = cell(1,11);
             n = 0 ;
             column = cell(1,11);
          for i = 1:length(datalist)
            load(fullfile(app.savedir,datalist(i).name),'exp');
            
            column = fieldnames(exp.metadata);
            mID = exp.metadata.mouseID;
           
            if mID == app.mouseID(value)
              
               if n == 0
               
                   history = (struct2cell(exp.metadata))';
                   n = 1;
               else  
                n = size(history,1);
                history(n+1,:) = (struct2cell(exp.metadata))';
               end
            end
          end
          
          app.UITable_2.Data = history(:,[1,7,11,10]);
          app.UITable_2.ColumnName = column([1,7,11,10]);
        end

        % Button pushed function: StopSetup1Button
        function StopSetup1(app, event)
           app.StopSetup1Button.Enable = 'off';
           
           %save stop to temporary file
           stop = 0;
           while stop==0
                try
                    stop = 1;
                    save(fullfile(app.tempdir,'setup1_stop'),'stop','-mat')
                catch
                    stop = 0;
                    pause(0.1)
                end
           end
                    
           %Set buttons back to initial setting 
           pause(1);
           app.RunSetup1Button.Enable = 'on';
        end

        % Button pushed function: StopSetup2Button
        function StopSetup2(app, event)
           app.StopSetup2Button.Enable = 'off';
           
           %save stop to temporary file
           stop = 0;
           while stop==0
                try
                    stop = 1;
                    save(fullfile(app.tempdir,'setup2_stop'),'stop','-mat')
                catch
                    stop = 0;
                    pause(0.1)
                end
           end
           
           %Set buttons back to initial setting 
           pause(1);
           app.RunSetup2Button.Enable = 'on';
        end

        % Value changed function: EnterWeightEditField
        function EnterWeight1(app, event)
            value = app.EnterWeightEditField.Value;
            app.setup1.weight = value; 
            
        end

        % Value changed function: EnterWeightEditField_2
        function EnterWeight2(app, event)
            value = app.EnterWeightEditField_2.Value;
            app.setup2.weight = value; 
        end

        % Button pushed function: LogWeightButton
        function LogWeight1(app, event)
            folder = app.savedir;
            filename = [app.setup1.metadata.datetime  ' ' app.setup1.metadata.mouseID ' weight'];
            save(fullfile(folder,filename),app.setup1.weight, app.setup1.metadata, '-mat')
        end

        % Button pushed function: LogWeightButton_2
        function LogWeight2(app, event)
            folder = app.savedir;
            filename = [app.setup2.metadata.datetime  ' ' app.setup2.metadata.mouseID ' weight'];
            save(fullfile(folder,filename),app.setup2.weight, app.setup2.metadata, '-mat')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Setup1Tab = uitab(app.TabGroup);
            app.Setup1Tab.Title = 'Setup 1';
            app.Panel = uipanel(app.Setup1Tab);
            app.Panel_2 = uipanel(app.Panel);
            app.DateandtimeLabel = uilabel(app.Panel_2);
            app.DateandtimeLabel.Text = 'Date and time';
            app.saveLabel = uilabel(app.Panel_2);
            app.saveLabel.Text = 'save ';
            app.ExperimentDropDownLabel = uilabel(app.Panel_2);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.Panel_2);
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @selectExp, true);
            app.UserDropDownLabel = uilabel(app.Panel_2);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.Panel_2);
            app.UserDropDown.ValueChangedFcn = createCallbackFcn(app, @selectUser, true);
            app.RunSetup1Button = uibutton(app.Panel_2, 'state');
            app.RunSetup1Button.ValueChangedFcn = createCallbackFcn(app, @RunSetup1, true);
            app.RunSetup1Button.Text = 'Run Setup 1';
            app.StopSetup1Button = uibutton(app.Panel_2, 'push');
            app.StopSetup1Button.ButtonPushedFcn = createCallbackFcn(app, @StopSetup1, true);
            app.StopSetup1Button.Text = 'Stop Setup 1';
            app.Panel_3 = uipanel(app.Panel);
            app.GridLayout = uigridlayout(app.Panel_3);
            app.AgeLabel = uilabel(app.GridLayout);
            app.AgeLabel.Text = 'Age';
            app.DobLabel = uilabel(app.GridLayout);
            app.DobLabel.Text = 'Dob';
            app.GenotypeLabel = uilabel(app.GridLayout);
            app.GenotypeLabel.Text = 'Genotype';
            app.SexLabel = uilabel(app.GridLayout);
            app.SexLabel.Text = 'Sex';
            app.GroupLabel = uilabel(app.GridLayout);
            app.GroupLabel.Text = 'Group';
            app.Panel_4 = uipanel(app.Panel);
            app.MouseIDDropDownLabel = uilabel(app.Panel_4);
            app.MouseIDDropDownLabel.Text = 'Mouse ID ';
            app.MouseIDDropDown = uidropdown(app.Panel_4);
            app.MouseIDDropDown.Items = {'Select a Mouse', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectID, true);
            app.MouseIDDropDown.Value = 'Select a Mouse';
            app.EnterWeightEditField = uieditfield(app.Panel_4, 'numeric');
            app.EnterWeightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterWeight1, true);
            app.WeightEditFieldLabel_1 = uilabel(app.Panel_4);
            app.WeightEditFieldLabel_1.Text = 'g';
            app.LogWeightButton = uibutton(app.Panel_4, 'push');
            app.LogWeightButton.ButtonPushedFcn = createCallbackFcn(app, @LogWeight1, true);
            app.LogWeightButton.Text = 'Log Weight';
            app.ExperimentHistoryPanel = uipanel(app.Panel);
            app.ExperimentHistoryPanel.Title = 'Experiment History';
            app.UITable = uitable(app.ExperimentHistoryPanel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Setup2Tab = uitab(app.TabGroup);
            app.Setup2Tab.Title = 'Setup 2';
            app.Panel_5 = uipanel(app.Setup2Tab);
            app.Panel_6 = uipanel(app.Panel_5);
            app.DateandtimeLabel_2 = uilabel(app.Panel_6);
            app.DateandtimeLabel_2.Text = 'Date and time';
            app.saveLabel_2 = uilabel(app.Panel_6);
            app.saveLabel_2.Text = 'save ';
            app.ExperimentDropDown_2Label = uilabel(app.Panel_6);
            app.ExperimentDropDown_2Label.Text = 'Experiment';
            app.ExperimentDropDown_2 = uidropdown(app.Panel_6);
            app.ExperimentDropDown_2.ValueChangedFcn = createCallbackFcn(app, @selectExp2, true);
            app.UserDropDown_2Label = uilabel(app.Panel_6);
            app.UserDropDown_2Label.Text = 'User';
            app.UserDropDown_2 = uidropdown(app.Panel_6);
            app.UserDropDown_2.ValueChangedFcn = createCallbackFcn(app, @selectUser2, true);
            app.RunSetup2Button = uibutton(app.Panel_6, 'state');
            app.RunSetup2Button.ValueChangedFcn = createCallbackFcn(app, @RunSetup2, true);
            app.RunSetup2Button.Text = 'Run Setup 2';
            app.StopSetup2Button = uibutton(app.Panel_6, 'push');
            app.StopSetup2Button.ButtonPushedFcn = createCallbackFcn(app, @StopSetup2, true);
            app.StopSetup2Button.Text = 'Stop Setup 2';
            app.Panel_7 = uipanel(app.Panel_5);
            app.GridLayout_2 = uigridlayout(app.Panel_7);
            app.AgeLabel_2 = uilabel(app.GridLayout_2);
            app.AgeLabel_2.Text = 'Age';
            app.DobLabel_2 = uilabel(app.GridLayout_2);
            app.DobLabel_2.Text = 'Dob';
            app.GenotypeLabel_2 = uilabel(app.GridLayout_2);
            app.GenotypeLabel_2.Text = 'Genotype';
            app.SexLabel_2 = uilabel(app.GridLayout_2);
            app.SexLabel_2.Text = 'Sex';
            app.GroupLabel_2 = uilabel(app.GridLayout_2);
            app.GroupLabel_2.Text = 'Group';
            app.Panel_8 = uipanel(app.Panel_5);
            app.MouseIDDropDown_2Label = uilabel(app.Panel_8);
            app.MouseIDDropDown_2Label.Text = 'Mouse ID ';
            app.MouseIDDropDown_2 = uidropdown(app.Panel_8);
            app.MouseIDDropDown_2.Items = {'Select a Mouse', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.MouseIDDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectID2, true);
            app.MouseIDDropDown_2.Value = 'Select a Mouse';
            app.EnterWeightEditField_2 = uieditfield(app.Panel_8, 'numeric');
            app.EnterWeightEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterWeight2, true);
            app.WeightEditFieldLabel_2 = uilabel(app.Panel_8);
            app.WeightEditFieldLabel_2.Text = 'g';
            app.LogWeightButton_2 = uibutton(app.Panel_8, 'push');
            app.LogWeightButton_2.ButtonPushedFcn = createCallbackFcn(app, @LogWeight2, true);
            app.LogWeightButton_2.Text = 'Log Weight';
            app.ExperimentHistoryPanel_2 = uipanel(app.Panel_5);
            app.ExperimentHistoryPanel_2.Title = 'Experiment History';
            app.UITable_2 = uitable(app.ExperimentHistoryPanel_2);
            app.UITable_2.ColumnName = {''; ''; ''; ''};
            app.UITable_2.RowName = {};
            app.ProgressTextLabel = uilabel(app.UIFigure);
            app.ProgressTextLabel.Text = 'ProgressText';
            app.ProgressTextLabel_2 = uilabel(app.UIFigure);
            app.ProgressTextLabel_2.Text = 'ProgressText';
            app.Setup1ProgressLabel = uilabel(app.UIFigure);
            app.Setup1ProgressLabel.Text = 'Setup 1 Progress';
            app.Setup2ProgressLabel = uilabel(app.UIFigure);
            app.Setup2ProgressLabel.Text = 'Setup 2 Progress';
            app.notify_pretestLabel = uilabel(app.UIFigure);
            app.notify_pretestLabel.Text = 'notify_pretest';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_mmc_pl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end